classdef Controlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure            % TRT Hybrid Control P...
        Gauge            matlab.ui.control.Gauge     % [0 100]
        Gauge2           matlab.ui.control.Gauge     % [0 200]
        LabelLamp        matlab.ui.control.Label     % Pressure sensor
        PressureLamp     matlab.ui.control.Lamp     
        Label5           matlab.ui.control.Label     % Working
        Label            matlab.ui.control.Label     % Load sensor
        LoadLamp         matlab.ui.control.Lamp     
        Label6           matlab.ui.control.Label     % Working
        Label2           matlab.ui.control.Label     % Electrovalve
        ElectrovalveLamp matlab.ui.control.Lamp     
        Label7           matlab.ui.control.Label     % Working, closed
        Label3           matlab.ui.control.Label     % Datalogger connection
        LoggerLamp       matlab.ui.control.Lamp     
        Label4           matlab.ui.control.Label     % Stable
        Label8           matlab.ui.control.Label     % No pressure
        Label11          matlab.ui.control.Label     % Launcher status
        Lamp6            matlab.ui.control.Lamp     
        Label9           matlab.ui.control.Label     % Not authorized
        Label10          matlab.ui.control.Label     % Authoritzation status
        AuthLamp         matlab.ui.control.Lamp     
        Label13          matlab.ui.control.Label     % Load [N]
        Label14          matlab.ui.control.Label     % (Not authorized)
        LaunchButton     matlab.ui.control.Button    % Launch
        PasswordField    matlab.ui.control.EditField
        Label15          matlab.ui.control.Label     % Password
        AuthBtn          matlab.ui.control.Button    % Request Authoritzation
        Label16          matlab.ui.control.Label     % Checked
        Label17          matlab.ui.control.Label     % Ignitor continuity
        IgnitorLamp      matlab.ui.control.Lamp     
        Button2          matlab.ui.control.Button    % Check
        Label18          matlab.ui.control.Label     % Pressure [psi]
        UIAxes           matlab.ui.control.UIAxes    % Pressure history
        UIAxes2          matlab.ui.control.UIAxes    % Load history
        ConnectBtn       matlab.ui.control.Button    % Connect
        Label19          matlab.ui.control.Label     % Time
        valveBtn         matlab.ui.control.Button    % Open valve
        Label20          matlab.ui.control.Label     % -00:10
    end


    properties (Access = public)
        logger % Description
    end

    properties (Access = private)
        pressureSensor % sensor object describing the pressure
        loadSensor % sensor object describing the load
        ignitorContinuity % sensor object describing the ignitor continuity
        electrovalve % actuator object describing the electrovalve
        authoritzationStatus % boolean describing the authoritzation status
        password % string with the password
        launchStatus % string with the password
        timer % Timer object
        time % Time integer
        beep % Beep sound
    end
    
    properties (Dependent)
        launcherStatus % Description
    end

    methods (Access = private)
        
        function results = checkPassword(app, password)
            results = strcmp(app.password, password);
            if (results)
                app.authoritzationStatus = 'Authorized';
            end
        end

        function results = updateView(app)
            if (~isempty(app.logger))
                app.Label4.Text = 'Stable';
                app.LoggerLamp.Color = [0 1 0];
                app.ConnectBtn.Text = 'Disconnect';
            elseif (isempty(app.logger))
                app.Label4.Text = 'Not connected';
                app.LoggerLamp.Color = [1 0 0];
                app.ConnectBtn.Text = 'Connect';
            else
                error('logger status not recoginzed');
            end
            
            if (strcmp(app.pressureSensor, 'Working'))
                app.Label5.Text = app.pressureSensor;
                app.PressureLamp.Color = [0 1 0];
            elseif (strcmp(app.pressureSensor, 'Not working'))
                app.Label5.Text = app.pressureSensor;
                app.PressureLamp.Color = [1 0 0];
            else
                error('pressureSensor status not recoginzed');
            end
            
            if (strcmp(app.loadSensor, 'Working'))
                app.Label5.Text = app.loadSensor;
                app.LoadLamp.Color = [0 1 0];
            elseif (strcmp(app.loadSensor, 'Not working'))
                app.Label5.Text = app.loadSensor;
                app.LoadLamp.Color = [1 0 0];
            else
                error('loadSensor status not recoginzed');
            end
            
            if (strcmp(app.electrovalve, 'Working, opened'))
                app.Label6.Text = app.electrovalve;
                app.ElectrovalveLamp.Color = [0 1 0];
            elseif (strcmp(app.electrovalve, 'Working, closed'))
                app.Label6.Text = app.electrovalve;
                app.ElectrovalveLamp.Color = [1 1 0];
            elseif (strcmp(app.electrovalve, 'Not working'))
                app.Label6.Text = app.electrovalve;
                app.ElectrovalveLamp.Color = [1 0 0];
            else
                error('electrovalve status not recoginzed');
            end
            
            if (strcmp(app.ignitorContinuity, 'Checked'))
                app.Label16.Text = app.ignitorContinuity;
                app.IgnitorLamp.Color = [0 1 0];
            elseif (strcmp(app.ignitorContinuity, 'Not checked'))
                app.Label16.Text = app.ignitorContinuity;
                app.IgnitorLamp.Color = [1 0 0];
            else
                error('ignitor continuity status not recoginzed');
            end
 
            if (strcmp(app.authoritzationStatus, 'Authorized'))
                app.AuthBtn.Text = 'Revoque Authoritzation';
                app.PasswordField.Enable = 'off';
                app.Label15.Enable = 'off';
                app.AuthLamp.Color = [0 1 0];
                app.Label9.Text = app.authoritzationStatus;
            elseif (strcmp(app.authoritzationStatus, 'Not authorized'))
                app.AuthBtn.Text = 'Request Authoritzation';
                app.PasswordField.Enable = 'on';
                app.Label15.Enable = 'on';
                app.AuthLamp.Color = [1 0 0];
                app.Label9.Text = app.authoritzationStatus;
            else
                error('authoritzation status not recoginzed');
            end
            
            if (strcmp(app.authoritzationStatus, 'Authorized') && ~isempty(app.logger))
                app.LaunchButton.Enable = 'on';
                app.valveBtn.Enable = 'on';
                app.Label14.Text = '';
            elseif (strcmp(app.authoritzationStatus, 'Not authorized'))
                app.LaunchButton.Enable = 'off';
                app.valveBtn.Enable = 'off';
                app.Label14.Text = '(Not authorized)';
            else 
                app.LaunchButton.Enable = 'off';
                app.valveBtn.Enable = 'off';
                app.Label14.Text = '(No connection)';
            end
            
            if (strcmp(app.launchStatus, 'launching'))
                app.LaunchButton.Text = 'Abort';
            elseif (strcmp(app.launchStatus, 'stopped'))
                app.LaunchButton.Text = 'Launch again';
            elseif (strcmp(app.launchStatus, ''))
                app.LaunchButton.Text = 'Launch';
            else
                error('launch status not recoginzed');
            end 
            
            if app.time < 0
                app.Label20.Text = ['-', num2str(floor(abs(app.time) / 60), '%02.0f'), ':',...
                num2str(abs(app.time) - floor(abs(app.time) / 60) * 60, '%02.0f')];
            else 
                app.Label20.Text = [num2str(floor(app.time / 60), '%02.0f'), ':',...
                num2str(app.time - floor(app.time / 60) * 60, '%02.0f')];
            end
        end
        
        function handleAcceptInitiateConnection(app, fig, port)
            app.inititateConnection(port);
            delete(fig);
            app.updateView();
        end
        
        function handleInitiateConnection(app) 
            % Opens a popup to select the COM port
            
            serialinfo = instrhwinfo('serial'); % Find serial info from hardware
        
            fig = uifigure(...
                'Name', 'Select COM port',...
                'Position', [600 500 270 120]...
            );
        
            dropdown = uidropdown(...
                fig,...
                'Items', serialinfo.AvailableSerialPorts,...
                'Position', [25, 55, 220, 50]...
            );
        
            uibutton(...
                fig,...
                'Text', 'Accept',...
                'Position', [145, 15, 100, 30],...
                'ButtonPushedFcn', @(~, ~) app.handleAcceptInitiateConnection(fig, dropdown.Value)...
            );
        
            uibutton(...
                fig,...
                'Text', 'Cancel',...
                'Position', [25, 15, 100, 30],...
                'ButtonPushedFcn', @(~, ~) delete(fig)...
            );
        end

        function inititateConnection(app, ComPort)
            % Actuators
            ignitorActuator = actuator_ignition('ignitor', 2);
            
            % Sensors
            continuitySensor = sensor_continuity('continuity', 5);
            
            % Conected devices cell array
            ConnectedDevices = {ignitorActuator, continuitySensor};
            
            SampleRate = 500; % Max 10000 for 1 channel
            
            % Initialize the logger
            app.logger = DataLogger(ComPort, SampleRate, ConnectedDevices);
            
            % Start getting data
            app.logger.getData(0, 1);
        end

        function results = terminateConnection(app)
            app.logger.stopGetData();
            delete(app.logger);
            app.logger=[];
        end

        function results = handleTimer(app, ~, ~)
            app.time = app.time + 1;
            app.updateView();
            
            if app.time < 0 && app.time > -8
                sound(app.beep{1},app.beep{2});
            end
            
            if app.time >= 0 && app.time < 3
                app.logger.enableDevice('ignitor') % Start the ignitor
            else
                app.logger.disableDevice('ignitor') % Stop the ignitor
            end 
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pressureSensor = 'Not working'; % sensor object describing the pressure
            app.loadSensor = 'Not working'; % sensor object describing the load
            app.ignitorContinuity = 'Not checked'; % sensor object describing the ignitor continuity
            app.electrovalve = 'Not working';
            app.authoritzationStatus = 'Not authorized';
            app.password = 'previsió'; % Set the password
            app.launchStatus = '';
            app.time = -10;
            
            app.updateView();
            
            app.timer = timer;
            app.timer.Period = 1;
            app.timer.ExecutionMode = 'fixedRate';
            app.timer.TimerFcn = @app.handleTimer;
            
            %Load beep
            [y,Fs] = audioread('beep.wav');
            app.beep = {y, Fs};
        end

        % AuthBtn button pushed function
        function handleRequestAuth(app)
            if (strcmp(app.authoritzationStatus, 'Authorized'))
                app.authoritzationStatus = 'Not authorized';
            elseif (strcmp(app.authoritzationStatus, 'Not authorized'))
                app.checkPassword(app.PasswordField.Value);
            end
            app.updateView();
        end

        % ConnectBtn button pushed function
        function handleConnectBtn(app)
            if (isempty(app.logger))
                app.handleInitiateConnection();
            else
                app.terminateConnection();
            end
            
            app.updateView();
        end

        % LaunchButton button pushed function
        function handleLaunchBtn(app)
            if (strcmp(app.launchStatus, '') || strcmp(app.launchStatus, 'stopped'))
                app.launchStatus = 'launching';
                app.time = -10;
                start(app.timer);
            else
                app.launchStatus = 'stopped';
                stop(app.timer);
            end
            
            app.updateView();
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            if (~isempty(app.logger))
                app.terminateConnection();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'TRT Hybrid Control Panel';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge2 = uigauge(app.UIFigure, 'circular');
            app.Gauge2.Limits = [0 200];
            app.LabelLamp = uilabel(app.UIFigure);
            app.LabelLamp.Text = 'Pressure sensor';
            app.PressureLamp = uilamp(app.UIFigure);
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Working';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Load sensor';
            app.LoadLamp = uilamp(app.UIFigure);
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Working';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Electrovalve';
            app.ElectrovalveLamp = uilamp(app.UIFigure);
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Working, closed';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Datalogger connection';
            app.LoggerLamp = uilamp(app.UIFigure);
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Stable';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'No pressure';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'Launcher status';
            app.Lamp6 = uilamp(app.UIFigure);
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Not authorized';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Authoritzation status';
            app.AuthLamp = uilamp(app.UIFigure);
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Text = 'Load [N]';
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Text = '(Not authorized)';
            app.LaunchButton = uibutton(app.UIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @handleLaunchBtn);
            app.LaunchButton.Enable = 'off';
            app.LaunchButton.Text = 'Launch';
            app.PasswordField = uieditfield(app.UIFigure, 'text');
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Text = 'Password';
            app.AuthBtn = uibutton(app.UIFigure, 'push');
            app.AuthBtn.ButtonPushedFcn = createCallbackFcn(app, @handleRequestAuth);
            app.AuthBtn.Text = 'Request Authoritzation';
            app.Label16 = uilabel(app.UIFigure);
            app.Label16.Text = 'Checked';
            app.Label17 = uilabel(app.UIFigure);
            app.Label17.Text = 'Ignitor continuity';
            app.IgnitorLamp = uilamp(app.UIFigure);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Check';
            app.Label18 = uilabel(app.UIFigure);
            app.Label18.Text = 'Pressure [psi]';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pressure history');
            xlabel(app.UIAxes, 'Time [s]');
            ylabel(app.UIAxes, 'Pressure [psi]');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Load history');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Load [N]');
            app.ConnectBtn = uibutton(app.UIFigure, 'push');
            app.ConnectBtn.ButtonPushedFcn = createCallbackFcn(app, @handleConnectBtn);
            app.ConnectBtn.Text = 'Connect';
            app.Label19 = uilabel(app.UIFigure);
            app.Label19.Text = 'Time';
            app.valveBtn = uibutton(app.UIFigure, 'push');
            app.valveBtn.Enable = 'off';
            app.valveBtn.Text = 'Open valve';
            app.Label20 = uilabel(app.UIFigure);
            app.Label20.Text = '-00:10';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Controlapp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end